classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        WybierzukadrwnaButtonGroup  matlab.ui.container.ButtonGroup
        Nr8Button                   matlab.ui.control.RadioButton
        Nr7Button                   matlab.ui.control.RadioButton
        Nr6Button                   matlab.ui.control.RadioButton
        Nr5Button                   matlab.ui.control.RadioButton
        Nr4Button                   matlab.ui.control.RadioButton
        Nr3Button                   matlab.ui.control.RadioButton
        Nr2Button                   matlab.ui.control.RadioButton
        Nr1Button                   matlab.ui.control.RadioButton
        ZakreswSlider               matlab.ui.control.RangeSlider
        ZakreswSliderLabel          matlab.ui.control.Label
        ZakreskSlider               matlab.ui.control.RangeSlider
        ZakreskSliderLabel          matlab.ui.control.Label
        kSlider                     matlab.ui.control.Slider
        kSliderLabel                matlab.ui.control.Label
        wSlider                     matlab.ui.control.Slider
        wSliderLabel                matlab.ui.control.Label
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        wartoscW = 0.5; % Description
        wartoscK = 10; % Description
        macierzA = [1,-3,2 ; -3,10,-5; 2,-5, 6]; % Description
        wektorB = [3;-8;8]; % Description
        maxK = 20;% Description
        minW = 0; % Description
        maxW = 2; % Description
        minK = 1; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [BlW, K] = daneDoWykresuBladAK(app.macierzA,app.wektorB,app.wartoscW, app.maxK);
            plot(app.UIAxes, K, BlW,'r-o'), legend(app.UIAxes, 'błąd względny dla x0 = [0,0,....,0]', 'Location','best');
            text(app.UIAxes);

            [W, BlWW] = DaneDoWykresuBladAW(app.macierzA,app.wektorB,app.wartoscK, app.minW, app.maxW);
            plot(app.UIAxes2, W, BlWW, "r-o"), legend(app.UIAxes2, 'błąd względny dla x0 = [0,0,....,0]', 'Location','best');
            text(app.UIAxes2);
        end

        % Value changing function: kSlider
        function kSliderValueChanging(app, event)
            changingValue = event.Value;
            app.wartoscK = round(changingValue);
            [W, BlWW] = DaneDoWykresuBladAW(app.macierzA,app.wektorB,app.wartoscK, app.minW, app.maxW);
            plot(app.UIAxes2, W, BlWW, "r-o"), legend(app.UIAxes2, 'błąd względny dla x0 = [0,0,....,0]', 'Location','best');
            text(app.UIAxes2);
        end

        % Value changing function: wSlider
        function wSliderValueChanging(app, event)
            changingValue = event.Value;
            app.wartoscW = changingValue;
            [BlW, K] = daneDoWykresuBladAK(app.macierzA,app.wektorB,app.wartoscW, app.maxK);
            plot(app.UIAxes, K, BlW,'r-o'), legend(app.UIAxes, 'błąd względny dla x0 = [0,0,....,0]', 'Location','best');
            text(app.UIAxes);
        end

        % Value changing function: ZakreswSlider
        function ZakreswSliderValueChanging(app, event)
            changingValue = event.Value;
            app.minW = changingValue(1);
            app.maxW = changingValue(2);
            [W, BlWW] = DaneDoWykresuBladAW(app.macierzA,app.wektorB,app.wartoscK, app.minW, app.maxW);
            plot(app.UIAxes2, W, BlWW, "r-o"), legend(app.UIAxes2, 'błąd względny dla x0 = [0,0,....,0]', 'Location','best');
            text(app.UIAxes2);
        end

        % Value changing function: ZakreskSlider
        function ZakreskSliderValueChanging(app, event)
            changingValue = event.Value;
            app.minK = round(changingValue(1)+1);
            app.maxK = round(changingValue(2));
            [BlW, K] = daneDoWykresuBladAK(app.macierzA,app.wektorB,app.wartoscW, app.maxK, app.minK);
            plot(app.UIAxes, K, BlW,'r-o'), legend(app.UIAxes, 'błąd względny dla x0 = [0,0,....,0]', 'Location','best');
            text(app.UIAxes);
            
        end

        % Selection changed function: WybierzukadrwnaButtonGroup
        function WybierzukadrwnaButtonGroupSelectionChanged(app, event)
            selectedButton = app.WybierzukadrwnaButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Nr 1'
                    app.macierzA = [1,-3,2 ; -3,10,-5; 2,-5, 6];
                    app.wektorB = [3;-8;8];
                case 'Nr 2'
                    app.macierzA = [1 0 0 -3;1 -1 0 0;0 0 1 1;0 0 -2 2];
                    app.wektorB = [3;-1;-2;1];
                case 'Nr 3'
                    app.macierzA = [1,4,7,-8,-9;
                                    0,8,5,3,8;
                                    9,0,6,5,-2;
                                    7,-8,0,-2,5;
                                    -9,0,0,6,1];
                    app.wektorB = [-35;5;13;-22;14];
                case 'Nr 4'
                    app.macierzA = [5 5 2;
                                    2 3 -4;
                                    0 9 1];
                    app.wektorB = [60; 33; 8];
                case 'Nr 5'
                    app.macierzA = [2 5 0;
                                    0 2 2;
                                    1 0 9];
                    app.wektorB = [36;30;84];
                case 'Nr 6'
                    app.macierzA = [4 -1 0;
                                    -1 4 -1;
                                    0 -1 4];
                    app.wektorB = [2;6;2];
                case 'Nr 7'
                    app.macierzA = [2 -9 0 0 0;
                                    2 1 8 6 0;
                                    0 11 -10 0 0;
                                    0 0 0 6 2;
                                    0 0 0 -3 3];
                    app.wektorB = [-16;52;-8;34;3];
                case 'Nr 8'
                    app.macierzA = [1 0 8 0 0;
                                    0 3 0 -5 -6;
                                    0 4 2 0 8;
                                    0 -3 3 1 0;
                                    0 2 0 0 4];
                    app.wektorB = [25;-14;46;13;78];
            end
            [BlW, K] = daneDoWykresuBladAK(app.macierzA,app.wektorB,app.wartoscW, app.maxK);
            plot(app.UIAxes, K, BlW,'r-o'), legend(app.UIAxes, 'błąd względny dla x0 = [0,0,....,0]', 'Location','best');
            text(app.UIAxes);

            [W, BlWW] = DaneDoWykresuBladAW(app.macierzA,app.wektorB,app.wartoscK, app.minW, app.maxW);
            plot(app.UIAxes2, W, BlWW, "r-o"), legend(app.UIAxes2, 'błąd względny dla x0 = [0,0,....,0]', 'Location','best');
            text(app.UIAxes2);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, {'Zmiana błędu względnego przybliżenia '; 'a ilość iteracji metody SOR'})
            xlabel(app.UIAxes, 'k - ilość iteracji metody')
            ylabel(app.UIAxes, 'błąd względny wyniku metody')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, {'Zmiana błędu przybliżenia '; 'a wartość parametru relaksacji'})
            xlabel(app.UIAxes2, 'w - parametr relaksacji')
            ylabel(app.UIAxes2, 'błąd względny wyniku metody')
            zlabel(app.UIAxes2, 'Z')
            app.wSliderLabel = uilabel(app.UIFigure);
            app.wSliderLabel.Text = 'w:';
            app.wSlider = uislider(app.UIFigure);
            app.wSlider.Limits = [-0.5 2.5];
            app.wSlider.MajorTicks = [-1 -0.5 0 0.5 1 1.5 2 2.5 3];
            app.wSlider.ValueChangingFcn = createCallbackFcn(app, @wSliderValueChanging, true);
            app.kSliderLabel = uilabel(app.UIFigure);
            app.kSliderLabel.Text = 'k:';
            app.kSlider = uislider(app.UIFigure);
            app.kSlider.ValueChangingFcn = createCallbackFcn(app, @kSliderValueChanging, true);
            app.ZakreskSliderLabel = uilabel(app.UIFigure);
            app.ZakreskSliderLabel.Text = 'Zakres k:';
            app.ZakreskSlider = uislider(app.UIFigure, 'range');
            app.ZakreskSlider.ValueChangingFcn = createCallbackFcn(app, @ZakreskSliderValueChanging, true);
            app.ZakreskSlider.Value = [0 20];
            app.ZakreswSliderLabel = uilabel(app.UIFigure);
            app.ZakreswSliderLabel.Text = 'Zakres w:';
            app.ZakreswSlider = uislider(app.UIFigure, 'range');
            app.ZakreswSlider.Limits = [-0.5 2.5];
            app.ZakreswSlider.MajorTicks = [-1 -0.5 0 0.5 1 1.5 2 2.5 3];
            app.ZakreswSlider.ValueChangingFcn = createCallbackFcn(app, @ZakreswSliderValueChanging, true);
            app.ZakreswSlider.Value = [0 2];
            app.WybierzukadrwnaButtonGroup = uibuttongroup(app.UIFigure);
            app.WybierzukadrwnaButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @WybierzukadrwnaButtonGroupSelectionChanged, true);
            app.WybierzukadrwnaButtonGroup.Title = 'Wybierz układ równań:';
            app.Nr1Button = uiradiobutton(app.WybierzukadrwnaButtonGroup);
            app.Nr1Button.Text = 'Nr 1';
            app.Nr1Button.Value = true;
            app.Nr2Button = uiradiobutton(app.WybierzukadrwnaButtonGroup);
            app.Nr2Button.Text = 'Nr 2';
            app.Nr3Button = uiradiobutton(app.WybierzukadrwnaButtonGroup);
            app.Nr3Button.Text = 'Nr 3';
            app.Nr4Button = uiradiobutton(app.WybierzukadrwnaButtonGroup);
            app.Nr4Button.Text = 'Nr 4';
            app.Nr5Button = uiradiobutton(app.WybierzukadrwnaButtonGroup);
            app.Nr5Button.Text = 'Nr 5';
            app.Nr6Button = uiradiobutton(app.WybierzukadrwnaButtonGroup);
            app.Nr6Button.Text = 'Nr 6';
            app.Nr7Button = uiradiobutton(app.WybierzukadrwnaButtonGroup);
            app.Nr7Button.Text = 'Nr 7';
            app.Nr8Button = uiradiobutton(app.WybierzukadrwnaButtonGroup);
            app.Nr8Button.Text = 'Nr 8';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end