classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        tSlider               matlab.ui.control.Slider
        tSliderLabel          matlab.ui.control.Label
        NmaxSlider            matlab.ui.control.Slider
        NmaxSliderLabel       matlab.ui.control.Label
        WywietldokadnwartofunkcjiysinxButtonGroup  matlab.ui.container.ButtonGroup
        NoButton              matlab.ui.control.RadioButton
        YesButton             matlab.ui.control.RadioButton
        ZatwierdzmianyButton  matlab.ui.control.Button
        UIAxes2               matlab.ui.control.UIAxes
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Nmax = 0; % Description
        wybranyButton = "1"; % Description
        wyswietlany = "1";
        X = -5:0.1:5; % Description
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn2(app)
            plot(app.UIAxes2, app.X, func(app.X),'r-', app.X, szeregSin(app.X,app.Nmax), 'b-'), legend(app.UIAxes2,"f(x) = sin(x)", "przybliżenie funkcji");
            text(app.UIAxes, app.X, func(app.X), ['(' num2str(app.X) ',' num2str(func(app.X)) ')']);
            text(app.UIAxes2, 0, 18, ['Dla Nmax = ' num2str(app.Nmax) '']);
            b = table2array(szeregBlad(0, 20));
            plot(app.UIAxes, 1:20, b(:,5)), legend(app.UIAxes,"błąd przybliżenia");
            text(app.UIAxes, 10, 15, ['Dla t = ' num2str(0) '']);

        end

        % Value changing function: NmaxSlider
        function NmaxSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Nmax = round(changingValue);
            switch app.wyswietlany
                case "1"
                    plot(app.UIAxes2, app.X, func(app.X),'r-', app.X, szeregSin(app.X,app.Nmax), 'b-'), , legend(app.UIAxes2,"f(x) = sin(x)", "przybliżenie funkcji");
                case "2"
                    plot(app.UIAxes2, app.X, szeregSin(app.X,app.Nmax), 'b-'), , legend(app.UIAxes2, "przybliżenie funkcji");
            end
            text(app.UIAxes2, 0, 18, ['Dla Nmax = ' num2str(app.Nmax) '']);

        end

        % Selection changed function: 
        % WywietldokadnwartofunkcjiysinxButtonGroup
        function WywietldokadnwartofunkcjiysinxButtonGroupSelectionChanged(app, event)
            selectedButton = app.WywietldokadnwartofunkcjiysinxButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Yes'
                    app.wybranyButton = "1";
                case 'No'
                    app.wybranyButton = "2";
            end
        end

        % Button pushed function: ZatwierdzmianyButton
        function ZatwierdzmianyButtonPushed(app, event)
            app.wyswietlany = app.wybranyButton;
            switch app.wyswietlany
                case "1"
                    plot(app.UIAxes2, app.X, func(app.X),'r-', app.X, szeregSin(app.X,app.Nmax), 'b-'), , legend(app.UIAxes2,"f(x) = sin(x)", "przybliżenie funkcji");
                case "2"
                    plot(app.UIAxes2, app.X, szeregSin(app.X,app.Nmax), 'b-'), , legend(app.UIAxes2, "przybliżenie funkcji");
            end
            text(app.UIAxes2, 0, 18, ['Dla Nmax = ' num2str(app.Nmax) '']);
        end

        % Value changing function: tSlider
        function tSliderValueChanging(app, event)
            changingValue = event.Value;
            b = table2array(szeregBlad(changingValue, 20));
            plot(app.UIAxes, 1:20, b(:,5)), legend(app.UIAxes,"błąd przybliżenia");
            text(app.UIAxes, 10, 15, ['Dla t = ' num2str(changingValue) '']);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Błąd przybliżenia funkcji f(x)=sin(x) w punkcie t')
            xlabel(app.UIAxes, 'Nmax')
            ylabel(app.UIAxes, 'Bład przybliżenia')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 20];
            app.UIAxes.YLim = [-13 17];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Przybliżenie funkcji y = sin(x)')
            xlabel(app.UIAxes2, 'x')
            ylabel(app.UIAxes2, 'y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XLim = [-5 5];
            app.UIAxes2.YLim = [-20 20];
            app.ZatwierdzmianyButton = uibutton(app.UIFigure, 'push');
            app.ZatwierdzmianyButton.ButtonPushedFcn = createCallbackFcn(app, @ZatwierdzmianyButtonPushed, true);
            app.ZatwierdzmianyButton.Text = 'Zatwierdź zmiany';
            app.WywietldokadnwartofunkcjiysinxButtonGroup = uibuttongroup(app.UIFigure);
            app.WywietldokadnwartofunkcjiysinxButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @WywietldokadnwartofunkcjiysinxButtonGroupSelectionChanged, true);
            app.WywietldokadnwartofunkcjiysinxButtonGroup.Title = 'Wyświetl dokładną wartość funkcji y = sin(x)';
            app.YesButton = uiradiobutton(app.WywietldokadnwartofunkcjiysinxButtonGroup);
            app.YesButton.Text = 'Yes';
            app.YesButton.Value = true;
            app.NoButton = uiradiobutton(app.WywietldokadnwartofunkcjiysinxButtonGroup);
            app.NoButton.Text = 'No';
            app.NmaxSliderLabel = uilabel(app.UIFigure);
            app.NmaxSliderLabel.Text = 'Nmax';
            app.NmaxSlider = uislider(app.UIFigure);
            app.NmaxSlider.Limits = [0 20];
            app.NmaxSlider.ValueChangingFcn = createCallbackFcn(app, @NmaxSliderValueChanging, true);
            app.tSliderLabel = uilabel(app.UIFigure);
            app.tSliderLabel.Text = 't';
            app.tSlider = uislider(app.UIFigure);
            app.tSlider.Limits = [-5 5];
            app.tSlider.ValueChangingFcn = createCallbackFcn(app, @tSliderValueChanging, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn2)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end